classdef GUI_optimisation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ParametertoupdateSwitch        matlab.ui.control.Switch
        ParametertoupdateSwitchLabel   matlab.ui.control.Label
        MirrorDropDown                 matlab.ui.control.DropDown
        MirrorDropDownLabel            matlab.ui.control.Label
        OptimisationalgorithmDropDown  matlab.ui.control.DropDown
        OptimisationalgorithmDropDownLabel  matlab.ui.control.Label
        FocallengthEditField           matlab.ui.control.EditField
        FocallengthEditFieldLabel      matlab.ui.control.Label
        WavelengthEditField            matlab.ui.control.EditField
        WavelengthEditFieldLabel       matlab.ui.control.Label
        MirrorchannelsEditField        matlab.ui.control.EditField
        MirrorchannelsEditFieldLabel   matlab.ui.control.Label
        ImageresolutionEditField       matlab.ui.control.EditField
        ImageresolutionEditFieldLabel  matlab.ui.control.Label
        PixelspacingEditField          matlab.ui.control.EditField
        PixelspacingEditFieldLabel     matlab.ui.control.Label
        BeamradiusEditField            matlab.ui.control.EditField
        BeamradiusEditFieldLabel       matlab.ui.control.Label
        FilterwheelconnectedLamp       matlab.ui.control.Lamp
        FilterwheelconnectedLampLabel  matlab.ui.control.Label
        MirrorconnectedLamp            matlab.ui.control.Lamp
        MirrorconnectedLampLabel       matlab.ui.control.Label
        EditFieldOD                    matlab.ui.control.EditField
        Lamp                           matlab.ui.control.Lamp
        EditFieldLR                    matlab.ui.control.EditField
        FWpositionOpticaldensitySpinner  matlab.ui.control.Spinner
        FWpositionOpticaldensitySpinnerLabel  matlab.ui.control.Label
        RecentreButton                 matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        DecaySpinner                   matlab.ui.control.Spinner
        DecaySpinnerLabel              matlab.ui.control.Label
        LearnrateSlider                matlab.ui.control.Slider
        LearnrateSliderLabel           matlab.ui.control.Label
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes1                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public) % call this app from command window using app = WSAO_gui();
        running
        dmirror % deformable mirror
        fw % filter wheel
        solver % learning algorithm
        voltages % actuator voltages
        channels % for now
        vid % video input
        cost_store % cost at each iteration
        image_resolution
        fhandle % function handle to cost
        maxI % maximum intensity
        mirror_sim % simulated mirror
        I_0 % max normalised intensity for perfect wavefront
        beam_radius % beam radius before focussing
        pixel_spacing % distance between pixels in camera
        focal_length % focal length of lens before camera
        wavelength % laser wavelength
        table
        recenter
        parameters % what the solver will optimise
        using_zernike_polys % option to use Zernike coefficients as optimisation parameters
        Z
        influence_matrix 
        initial_ff
        last_ff
        initial_sr
        last_sr
        
    end

    methods (Access = public)
        function cost = cost_function(app,pos)
            % Returns FF Quality metric from solver position
            % Solver position will be between -1:1, driver box wants inputs in
            % millivolts
            cost_case = 4;
            if isscalar(pos)
                pos = ones(props.parameters,1)*pos;
            end
            if app.using_zernike_polys == 1
                v = coeffs2voltages(pos,app);
            else
                v = floor(pos*50*1000); % Solver coord space -> voltage space
            end
            app.dmirror.setChannels(v);
            app.table.Data = [(1:app.channels)',v];
    
            pause(0.1)
            % [~,outff] = strehl_ratio(cam,fw);
            currentff = get_norm_image(app); % this needs to be changed to getsnapshot() stuff
            
            app.mirror_sim.set_channels(v)% function to display approximage mirror shape on model
            
            s = surf(app.UIAxes5,app.mirror_sim.shape); % honestly removing this would probably speed things up quite a bit
            set(s, 'EdgeColor', 'interp', 'FaceColor', 'interp');
            colormap(app.UIAxes5,spring)
            app.maxI = max(currentff,[],'all');
   
            switch cost_case
                case 1
                    cost = -(app.maxI * 10^(app.fw.slots(app.fw.position)))/750;
                case 2
                    cost = -app.maxI;
                case 3
                    cost = sum(currentff(:).^2)/(sum(currentff(:))^2) * app.image_resolution^2;
                case 4
                    cost = sum(currentff,'all');
            end                
        end

        function centre_video(app)
            dims = app.vid.VideoResolution;
            app.vid.ROIPosition = [0,0,dims(1),dims(2)];
            frame = get_norm_image(app);
            % centroid stuff
            
            labeledImage = logical(true(size(frame)));
            max_of_frame = frame .* (frame == max(frame,[],'all'));
            props = regionprops(labeledImage, max_of_frame, 'Centroid', 'WeightedCentroid');
            c = floor(props.WeightedCentroid);
            if any([c(1) < app.image_resolution,c(1) > dims(1)-app.image_resolution,c(2) < app.image_resolution,c(2)>dims(2)-app.image_resolution],'all') % make more general
                %preview(app.vid)
                error('Laser beyond edges');
            end
            app.vid.ROIPosition = [c(1)-app.image_resolution/2,c(2)-app.image_resolution/2,app.image_resolution,app.image_resolution];
        end

        function frame = get_norm_image(app)
            frame = get_frame(app);
            app.maxI = max(frame,[],'all');
            if app.maxI < 0.01
                assert(app.fw.position~=1,"No laser detected")
                app.fw.position = app.fw.position - 1;
                app.fw.setPosition(app.fw.position)
                pause(1);
                frame = get_frame(app);
                app.maxI = max(frame,[],'all');
            end
            while app.maxI >= 1
                app.fw.position = app.fw.position + 1;
                app.fw.setPosition(app.fw.position)
                pause(1);
                frame = get_frame(app);
                pause(1)
                app.maxI = max(frame,[],'all');
           end
            app.FWpositionOpticaldensitySpinner.Value = app.fw.position;
            app.EditFieldOD.Value = string(app.fw.slots(app.fw.position));
            frame = frame/app.maxI;
        end

        function frame = get_frame(app)
            frame  = double(getsnapshot(app.vid));
            frame = frame/4091;
            
            frame = medfilt2(frame,[3 3]);
            %frame(frame < 0.004) = 0;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            addpath('WSAO')
            addpath(fullfile('WSAO','AO Simulation'));
            addpath(fullfile('WSAO','Optimisation Algorithms'));
            addpath(fullfile('WSAO','Utilities'));
            addpath(fullfile('WSAO','AO Simulation','Mirror presets'));
            app.EditFieldOD.Editable = "off";
            app.ImageresolutionEditField.Editable = "off";
            app.PixelspacingEditField.Editable = "off";
            app.BeamradiusEditField.Editable = "off";
            app.WavelengthEditField.Editable = "off";
            app.FocallengthEditField.Editable = "off";
            app.MirrorchannelsEditField.Editable = "off";
            app.MirrorDropDown.Editable = "off";
            app.OptimisationalgorithmDropDown.Editable = "off";
           % app.ParametertoupdateS


            app.image_resolution = str2double(app.ImageresolutionEditField.Value);
            app.beam_radius = str2double(app.BeamradiusEditField.Value);
            app.pixel_spacing = str2double(app.PixelspacingEditField.Value);
            app.wavelength = str2double(app.WavelengthEditField.Value);
            app.focal_length = str2double(app.FocallengthEditField.Value);
            app.channels = str2double(app.MirrorchannelsEditField.Value);
            app.table = uitable(app.UITable);

            %% simulate perfect wavefront to find I_0
            perfect_ff = scaled_perfect_farfield(app);
            app.I_0 = strehl_ratio(perfect_ff);
            
            %% connect filter wheel
            app.fw = filter_wheel("COM5",115200); 
            if app.fw.isconnected == 1
                app.FilterwheelconnectedLamp.Color = [0 1 0];
                app.FilterwheelconnectedLamp.Enable = "on";
            end
            
            pause(1)
            app.fw.setPosition(3) % so that it starts in middle - camera not just getting blasted while things set up
            pause(1)
            %% connect mirror
            app.dmirror = mirror_real("COM4",115200); 
            if app.dmirror.isconnected == 1
                app.MirrorconnectedLamp.Color = [0 1 0];
                app.MirrorconnectedLamp.Enable = "on";
            end
            app.dmirror.setChannels(0);

            %% create simulated mirror
            mirror_sim_name = app.MirrorDropDown.Value;
            if mirror_sim_name == "default_square"
                app.mirror_sim = mirror_model(app.channels,app.image_resolution,0.09);
            else
                load(dir(fullfile(what('Mirror Presets').path,mirror_sim_name)).name,'influences');
                app.mirror_sim = mirror_model(influences);
                app.mirror_sim.set_mirror_cutout('O')
            end

            if app.using_zernike_polys
                app.parameters=12;
                [r,theta] = generate_grid(app.mirror_sim.mirror_resolution,"polar");
                pupil = (r<=1);
                r = r(pupil);
                theta = theta(pupil);
                [N,M] = generate_orders(2,9);
                app.Z = myzernike(N,M,r,theta);
                app.Z = app.Z(:,1:app.parameters); % needed for coeffs2voltages function
                cutout = app.mirror_sim.cutout==1;
                cutout_repeat = repmat(cutout,1,1,app.mirror_sim.channels);
                all_useful_points = app.mirror_sim.influences(cutout_repeat);
                app.influence_matrix = reshape(all_useful_points,[],app.mirror_sim.channels);
            else
                app.parameters = app.channels;
            end

            %% load video and initial variables
            app.vid = videoinput('gige',1,'Mono12'); % could make this more versatile by having it read ip from gigecamlist
            src = getselectedsource(app.vid);
            src.PacketDelay = 12460;
            centre_video(app); % centre on focus
            app.EditFieldLR.Value = string(app.LearnrateSlider.Value);
            

            %% Create solver
            app.fhandle = @(r) app.cost_function(r); % create cost function
            solver_name = str2func(app.OptimisationalgorithmDropDown.Value);
            app.solver = solver_name(app.parameters,app.fhandle); % create solver
            app.solver.learningRate = app.LearnrateSlider.Value;
            app.solver.decay_rate = app.DecaySpinner.Value;
            
            %% load initial images
            %f = get_frame(app);
            app.initial_ff = get_norm_image(app);
            pause(0.5)
            imagesc(app.UIAxes1,app.initial_ff);
            app.initial_sr = strehl_ratio(app.initial_ff(1:app.image_resolution,1:app.image_resolution))/app.I_0;
            %im2 = image(app.UIAxes2,zeros(app.image_resolution,app.image_resolution,'uint8')); % define where exactly the image is
            title(app.UIAxes1,['Initial image - cost = ' num2str(app.solver.cost)])
            app.cost_store = app.solver.cost;
            pause(1)
            preview(app.vid,im2)
            pause(1) % idk why it needs this pause but it didn't work without it
            title(app.UIAxes2,'Live camera view')
            %% endless loop
            app.running = true;
            app.voltages = app.dmirror.voltages; % should all be 0 at this point
            app.table.Data = [(1:app.channels)',app.voltages];
            app.Lamp.Color = [0 1 0];
            app.Lamp.Enable = "on";
            while app.running
                app.LearnrateSlider.Value = app.solver.learningRate;
                app.EditFieldLR.Value = string(app.solver.learningRate);
                app.solver.step();

                app.cost_store = [app.cost_store app.solver.cost];
                
                app.last_ff = get_norm_image(app);
                imagesc(app.UIAxes3,app.last_ff);
                app.last_sr = strehl_ratio(app.last_ff)/app.I_0;
                title(app.UIAxes3,['Last iteration - cost = ' num2str(app.solver.cost)])
                plot(app.UIAxes4,0:length(app.cost_store)-1,app.cost_store)
                xlabel('Iteration')
                ylabel('Cost')
                if app.recenter
                    closepreview(app.vid) % it can get upset when changing roi position with preview running
                    centre_video(app)
                    preview(app.vid,im2)
                    pause(1)
                    app.recenter = 0;
                end
            end
            %% when stop button pressed
            app.Lamp.Color = [1 0 0];
            app.Lamp.Enable = "off";
            delete(app.fw)
            app.FilterwheelconnectedLamp.Color = [1 0 0];
            app.FilterwheelconnectedLamp.Enable = "off";
            delete(app.dmirror)
            app.MirrorconnectedLamp.Color = [1 0 0];
            app.MirrorconnectedLamp.Enable = "off";
            app.ImageresolutionEditField.Editable = "on";
            app.PixelspacingEditField.Editable = "on";
            app.BeamradiusEditField.Editable = "on";
            app.WavelengthEditField.Editable = "on";
            app.FocallengthEditField.Editable = "on";
            app.MirrorchannelsEditField.Editable = "on";
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.running = false;
        end

        % Value changed function: LearnrateSlider
        function LearnrateSliderValueChanged(app, event)
            app.solver.learningRate = app.LearnrateSlider.Value;
            app.EditFieldLR.Value = string(app.LearnrateSlider.Value);
        end

        % Value changed function: DecaySpinner
        function DecaySpinnerValueChanged(app, event)
            app.solver.decay_rate = app.DecaySpinner.Value;
        end

        % Value changing function: LearnrateSlider
        function LearnrateSliderValueChanging(app, event)
            app.EditFieldLR.Value = string(event.Value);
        end

        % Value changed function: FWpositionOpticaldensitySpinner
        function FWpositionOpticaldensitySpinnerValueChanged(app, event)
            value = app.FWpositionOpticaldensitySpinner.Value;
            app.fw.setPosition(value)
            app.EditFieldOD.Value = string(app.fw.slots(app.fw.position));
            pause(0.2)       % give it time to move     
        end

        % Button pushed function: RecentreButton
        function RecentreButtonPushed(app, event)
            app.recenter = 1;
        end

        % Callback function
        function OptimisationalgorithmDropDownValueChanged(app, event)

        end

        % Callback function
        function MirrorDropDownValueChanged(app, event)

        end

        % Value changed function: ParametertoupdateSwitch
        function ParametertoupdateSwitchValueChanged(app, event)
            value = app.ParametertoupdateSwitch.Value;
            switch value
                case 'Zernike polynomials'
                    app.using_zernike_polys=1;
                case 'Mirror voltages'
                    app.using_zernike_polys=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes1 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes5, 'Z')
            app.LearnrateSliderLabel = uilabel(app.UIFigure);
            app.LearnrateSliderLabel.Text = 'Learn rate';
            app.LearnrateSlider = uislider(app.UIFigure);
            app.LearnrateSlider.Limits = [0 0.2];
            app.LearnrateSlider.ValueChangedFcn = createCallbackFcn(app, @LearnrateSliderValueChanged, true);
            app.LearnrateSlider.ValueChangingFcn = createCallbackFcn(app, @LearnrateSliderValueChanging, true);
            app.LearnrateSlider.Value = 0.05;
            app.DecaySpinnerLabel = uilabel(app.UIFigure);
            app.DecaySpinnerLabel.Text = 'Decay';
            app.DecaySpinner = uispinner(app.UIFigure);
            app.DecaySpinner.ValueChangedFcn = createCallbackFcn(app, @DecaySpinnerValueChanged, true);
            app.DecaySpinner.Value = 0.97;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Actuator'; 'Voltages/mV'};
            app.UITable.RowName = {};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RecentreButton = uibutton(app.UIFigure, 'push');
            app.RecentreButton.ButtonPushedFcn = createCallbackFcn(app, @RecentreButtonPushed, true);
            app.RecentreButton.Text = 'Recentre';
            app.FWpositionOpticaldensitySpinnerLabel = uilabel(app.UIFigure);
            app.FWpositionOpticaldensitySpinnerLabel.Text = {'FW position : '; ''; 'Optical density :'};
            app.FWpositionOpticaldensitySpinner = uispinner(app.UIFigure);
            app.FWpositionOpticaldensitySpinner.ValueChangedFcn = createCallbackFcn(app, @FWpositionOpticaldensitySpinnerValueChanged, true);
            app.EditFieldLR = uieditfield(app.UIFigure, 'text');
            app.EditFieldLR.Value = '0.05';
            app.Lamp = uilamp(app.UIFigure);
            app.EditFieldOD = uieditfield(app.UIFigure, 'text');
            app.MirrorconnectedLampLabel = uilabel(app.UIFigure);
            app.MirrorconnectedLampLabel.Text = 'Mirror connected';
            app.MirrorconnectedLamp = uilamp(app.UIFigure);
            app.FilterwheelconnectedLampLabel = uilabel(app.UIFigure);
            app.FilterwheelconnectedLampLabel.Text = 'Filter wheel connected';
            app.FilterwheelconnectedLamp = uilamp(app.UIFigure);
            app.BeamradiusEditFieldLabel = uilabel(app.UIFigure);
            app.BeamradiusEditFieldLabel.Text = 'Beam radius';
            app.BeamradiusEditField = uieditfield(app.UIFigure, 'text');
            app.BeamradiusEditField.Value = '1.3478e-3';
            app.PixelspacingEditFieldLabel = uilabel(app.UIFigure);
            app.PixelspacingEditFieldLabel.Text = 'Pixel spacing';
            app.PixelspacingEditField = uieditfield(app.UIFigure, 'text');
            app.PixelspacingEditField.Value = '5.86e-6';
            app.ImageresolutionEditFieldLabel = uilabel(app.UIFigure);
            app.ImageresolutionEditFieldLabel.Text = 'Image resolution';
            app.ImageresolutionEditField = uieditfield(app.UIFigure, 'text');
            app.ImageresolutionEditField.Value = '300';
            app.MirrorchannelsEditFieldLabel = uilabel(app.UIFigure);
            app.MirrorchannelsEditFieldLabel.Text = 'Mirror channels';
            app.MirrorchannelsEditField = uieditfield(app.UIFigure, 'text');
            app.MirrorchannelsEditField.Value = '55';
            app.WavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.UIFigure, 'text');
            app.WavelengthEditField.Value = '636e-9';
            app.FocallengthEditFieldLabel = uilabel(app.UIFigure);
            app.FocallengthEditFieldLabel.Text = 'Focal length';
            app.FocallengthEditField = uieditfield(app.UIFigure, 'text');
            app.FocallengthEditField.Value = '15e-2';
            app.OptimisationalgorithmDropDownLabel = uilabel(app.UIFigure);
            app.OptimisationalgorithmDropDownLabel.Text = 'Optimisation algorithm';
            app.OptimisationalgorithmDropDown = uidropdown(app.UIFigure);
            app.OptimisationalgorithmDropDown.Items = {'adam_individual', 'adam', 'ant_colony', 'gdm_solver', 'gdm_solver_individual', 'nm_simplex', 'pattern_search', 'coordinate_search'};
            app.OptimisationalgorithmDropDown.Value = 'adam_individual';
            app.MirrorDropDownLabel = uilabel(app.UIFigure);
            app.MirrorDropDownLabel.Text = 'Mirror';
            app.MirrorDropDown = uidropdown(app.UIFigure);
            app.MirrorDropDown.Items = {'real_model_55.mat', 'default_square'};
            app.MirrorDropDown.Value = 'real_model_55.mat';
            app.ParametertoupdateSwitchLabel = uilabel(app.UIFigure);
            app.ParametertoupdateSwitchLabel.Text = 'Parameter to update';
            app.ParametertoupdateSwitch = uiswitch(app.UIFigure, 'slider');
            app.ParametertoupdateSwitch.Items = {'Mirror voltages', 'Zernike polynomials'};
            app.ParametertoupdateSwitch.ValueChangedFcn = createCallbackFcn(app, @ParametertoupdateSwitchValueChanged, true);
            app.ParametertoupdateSwitch.Value = 'Mirror voltages';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_optimisation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end